classdef Smoother2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SURFACESMOOTHERLabel            matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        SetupSmootherTab                matlab.ui.container.Tab
        EditField                       matlab.ui.control.EditField
        EnterpathoffortrancompilertypewhichgfortranonterminalLabel  matlab.ui.control.Label
        RunSmootherTab                  matlab.ui.container.Tab
        ParametersofthesurfacesmootherPanel  matlab.ui.container.Panel
        NumberofrefinemensDropDownLabel  matlab.ui.control.Label
        NumberofrefinemensDropDown      matlab.ui.control.DropDown
        AdaptiveflagDropDownLabel       matlab.ui.control.Label
        AdaptiveflagDropDown            matlab.ui.control.DropDown
        Nameofthego3fileEditFieldLabel  matlab.ui.control.Label
        Nameofthego3fileEditField       matlab.ui.control.EditField
        SmoothingparameterEditFieldLabel  matlab.ui.control.Label
        SmoothingparameterEditField     matlab.ui.control.NumericEditField
        Slider_5                        matlab.ui.control.Slider
        RunSmootherButton               matlab.ui.control.Button
        OrderofthequadratureruleEditFieldLabel  matlab.ui.control.Label
        OrderofthequadratureruleEditField  matlab.ui.control.NumericEditField
        OrderofthesmoothsurfaceEditFieldLabel  matlab.ui.control.Label
        OrderofthesmoothsurfaceEditField  matlab.ui.control.NumericEditField
        PrepareButton                   matlab.ui.control.Button
        SkeletonPanel                   matlab.ui.container.Panel
        SkeletonfigurepropertiesPanel   matlab.ui.container.Panel
        Slider_6                        matlab.ui.control.Slider
        AxisDropDown_3Label             matlab.ui.control.Label
        AxisDropDown_3                  matlab.ui.control.DropDown
        LightDropDown_3Label            matlab.ui.control.Label
        LightDropDown_3                 matlab.ui.control.DropDown
        RotationaxisLabel               matlab.ui.control.Label
        RotationaxisDropDown            matlab.ui.control.DropDown
        ColorDropDown_3Label            matlab.ui.control.Label
        ColorDropDown_3                 matlab.ui.control.DropDown
        ShadingDropDown_3Label          matlab.ui.control.Label
        ShadingDropDown_3               matlab.ui.control.DropDown
        ResolutionEditField_2Label      matlab.ui.control.Label
        ResolutionEditField_2           matlab.ui.control.NumericEditField
        ProjectionDropDown_3Label       matlab.ui.control.Label
        ProjectionDropDown_3            matlab.ui.control.DropDown
        OpenmshButton                   matlab.ui.control.Button
        showpathCheckBox                matlab.ui.control.CheckBox
        skeletonEditFieldLabel          matlab.ui.control.Label
        skeletonEditField               matlab.ui.control.EditField
        SmoothSurfacePanel              matlab.ui.container.Panel
        SmoothsurfacefigurepropertiesPanel  matlab.ui.container.Panel
        ErrorDropDown                   matlab.ui.control.DropDown
        ShowErrorCheckBox               matlab.ui.control.CheckBox
        Slider_7                        matlab.ui.control.Slider
        AxisDropDown_4Label             matlab.ui.control.Label
        AxisDropDown_4                  matlab.ui.control.DropDown
        LightDropDown_4Label            matlab.ui.control.Label
        LightDropDown_4                 matlab.ui.control.DropDown
        RotationaxisDropDown_2Label     matlab.ui.control.Label
        RotationaxisDropDown_2          matlab.ui.control.DropDown
        ColorDropDown_4Label            matlab.ui.control.Label
        ColorDropDown_4                 matlab.ui.control.DropDown
        ShadingDropDown_4Label          matlab.ui.control.Label
        ShadingDropDown_4               matlab.ui.control.DropDown
        ResolutionEditField_3Label      matlab.ui.control.Label
        ResolutionEditField_3           matlab.ui.control.NumericEditField
        ProjectionDropDown_4Label       matlab.ui.control.Label
        ProjectionDropDown_4            matlab.ui.control.DropDown
        Opengo3Button                   matlab.ui.control.Button
        go3fileEditFieldLabel           matlab.ui.control.Label
        go3fileEditField                matlab.ui.control.EditField
        showpathCheckBox_2              matlab.ui.control.CheckBox
        TextArea                        matlab.ui.control.TextArea
        SelectskeletonLabel             matlab.ui.control.Label
        SelectsmoothingparametersLabel  matlab.ui.control.Label
        ViewresultingsmoothsurfaceLabel  matlab.ui.control.Label
        CheckIntersectionPanel          matlab.ui.container.Panel
        ListListBoxLabel                matlab.ui.control.Label
        ListListBox                     matlab.ui.control.ListBox
        TransparencySlider              matlab.ui.control.Slider
        RemovefromlistButton            matlab.ui.control.Button
        SameTraspButton                 matlab.ui.control.Button
        CheckIntersButton               matlab.ui.control.Button
        TransparencyEditFieldLabel      matlab.ui.control.Label
        TransparencyEditField           matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        skeleton_color 
        skeleton_shading 
        skeleton_projection 
        skeleton_resolution 
        skeleton_light
        skeleton_filename
        skeleton_path='';
        skeleton_h_figure
        skeleton_h_axes
        ssurf_h_figure
        ssurf_h_axes
        compiler_path
        ssurf_path='';
        ssurf_filename
        
        g_sm_ntri
        g_sm_norder
        g_sm_npoints
        g_sm_srcvals
        g_sm_tri_flat_tot
        g_sm_F_x
        g_sm_F_y
        g_sm_F_z
        g_sm_COL
        g_sm_L2
        g_sm_L1
        g_sm_Lsup
        
        g_sk_Points
        g_sk_Tri
        g_sk_n_tri
        
        g_sk_tri_flat_tot
        g_sk_F_x
        g_sk_F_y
        g_sk_F_z
        g_sk_COL
        
        my_cell=cell(0,0);

    end
    
    methods (Access = private)
        
        function plot_skeleton(app,file)
            if isvalid(app.skeleton_h_axes)
            else
                app.skeleton_h_figure=figure('Visible','off');
                app.skeleton_h_axes=axes;
                view(50,20)
            end
%            [Geometry]=lee_msh_app(file,app.skeleton_h_axes);
            [az,el] = view(app.skeleton_h_axes);
            lee_msh_app2(file,app.skeleton_h_axes,app.Slider_6.Value,app.ColorDropDown_3.Value,app.RotationaxisDropDown.Value)
            set(app.skeleton_h_figure, 'Visible','on')
            rotate3d(app.skeleton_h_axes,'on')
            axis(app.skeleton_h_axes,'equal')
            view(az,el);
            event=0;
            ProjectionDropDown_3ValueChanged(app, event)
            AxisDropDown_3ValueChanged(app, event)
            LightDropDown_3ValueChanged(app, event)
            ShadingDropDown_3ValueChanged(app, event)
            
        end
    
        function update_plot_skeleton(app)
           file=app.skeleton_filename;
           path=app.skeleton_path;     
           if isfile([path file])
              plot_skeleton(app,[path file])                
           end
        end

    
        function plot_ssurf(app,file)
            if isvalid(app.ssurf_h_axes)
            else
                app.ssurf_h_figure=figure('Visible','off');
                app.ssurf_h_axes=axes;
                view(50,20)
            end
%            [Geometry]=lee_msh_app(file,app.skeleton_h_axes);
            [az,el] = view(app.ssurf_h_axes);
            plot_go3(file,app.ssurf_h_axes,app.Slider_7.Value,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
            set(app.ssurf_h_figure, 'Visible','on')
            rotate3d(app.ssurf_h_axes,'on')
            axis(app.ssurf_h_axes,'equal')
            view(az,el);
            event=0;
            ProjectionDropDown_4ValueChanged(app, event)
            AxisDropDown_4ValueChanged(app, event)
            LightDropDown_4ValueChanged(app, event)
            ShadingDropDown_4ValueChanged(app, event)            
        end
    
        function update_plot_ssurf(app)
           file=app.ssurf_filename;
           path=app.ssurf_path;     
           if isfile([path file])
              plot_ssurf(app,[path file])                
           end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.skeleton_h_figure=figure('Visible','off');
            app.skeleton_h_axes=axes;
            view(50,20)
            setenv('PATH', [getenv('PATH') ':' app.EditField.Value]);
            app.ssurf_h_figure=figure('Visible','off');
            app.ssurf_h_axes=axes;
            view(50,20)
            app.skeleton_filename='';
            app.ssurf_filename='';            
            event=0;
            ProjectionDropDown_3ValueChanged(app, event)
            AxisDropDown_3ValueChanged(app, event)
            LightDropDown_3ValueChanged(app, event)
            ShadingDropDown_3ValueChanged(app, event)
            ProjectionDropDown_4ValueChanged(app, event)
            AxisDropDown_4ValueChanged(app, event)
            LightDropDown_4ValueChanged(app, event)
            ShadingDropDown_4ValueChanged(app, event)

        end

        % Button pushed function: OpenmshButton
        function OpenmshButtonPushed(app, event)
            [file,path,indx] = uigetfile([app.skeleton_path '*.msh']);  
            if isequal(file,0)
            else
                [app.g_sk_Points,app.g_sk_Tri,app.g_sk_n_tri]=open_msh([path file]);
                
                 app.skeleton_filename=file;
                 app.skeleton_path=path;
%                 plot_ssurf(app,[path file])
                 if app.showpathCheckBox.Value==1
                      app.skeletonEditField.Value=[path file];
                 else
                      app.skeletonEditField.Value=file;
                 end
                 
                 if isvalid(app.skeleton_h_axes)
                 else
                     app.skeleton_h_figure=figure('Visible','off');
                     app.skeleton_h_axes=axes;
                     view(50,20)
                 end
                [az,el] = view(app.skeleton_h_axes);
                [app.g_sk_tri_flat_tot,app.g_sk_F_x,app.g_sk_F_y,app.g_sk_F_z,app.g_sk_COL]=load_flattri_skeleton(app.g_sk_Points,app.g_sk_Tri,app.g_sk_n_tri,app.Slider_6.Value)
                
                
                refresh_geometry(app.g_sk_tri_flat_tot,app.g_sk_F_x,app.g_sk_F_y,app.g_sk_F_z,app.g_sk_COL,[],[],[],app.skeleton_h_axes,app.ColorDropDown_3.Value,app.RotationaxisDropDown.Value,app.ErrorDropDown.Value,app.ShowErrorCheckBox.Value,1)               
%               plot_go3(file,app.ssurf_h_axes,app.Slider_7.Value,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
                set(app.skeleton_h_figure, 'Visible','on')
                rotate3d(app.skeleton_h_axes,'on')
                axis(app.skeleton_h_axes,'equal')
                view(az,el);
                event=0;
                ProjectionDropDown_3ValueChanged(app, event)
                AxisDropDown_3ValueChanged(app, event)
                LightDropDown_3ValueChanged(app, event)
                ShadingDropDown_3ValueChanged(app, event)            
            end  

        end

        % Value changed function: ColorDropDown_3
        function ColorDropDown_3ValueChanged(app, event)
            value = app.ColorDropDown_3.Value;

            if length(app.g_sk_F_x)>0
                 if isvalid(app.skeleton_h_axes)
                 else
                     app.skeleton_h_figure=figure('Visible','off');
                     app.skeleton_h_axes=axes;
                     view(50,20)
                 end
                [az,el] = view(app.skeleton_h_axes);
%                [app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL]=load_flattri_smooth_geometry(app.g_sm_ntri,app.g_sm_norder,app.g_sm_npoints,app.g_sm_srcvals,app.Slider_7.Value)

                refresh_geometry(app.g_sk_tri_flat_tot,app.g_sk_F_x,app.g_sk_F_y,app.g_sk_F_z,app.g_sk_COL,[],[],[],app.skeleton_h_axes,app.ColorDropDown_3.Value,app.RotationaxisDropDown.Value,app.ErrorDropDown.Value,app.ShowErrorCheckBox.Value,1)               
%refresh_geometry(app.g_sk_tri_flat_tot,app.g_sk_F_x,app.g_sk_F_y,app.g_sk_F_z,app.g_sk_COL,app.skeleton_h_axes,app.ColorDropDown_3.Value,app.RotationaxisDropDown.Value)               

%               plot_go3(file,app.ssurf_h_axes,app.Slider_7.Value,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
                set(app.skeleton_h_figure, 'Visible','on')
                rotate3d(app.skeleton_h_axes,'on')
                axis(app.skeleton_h_axes,'equal')
                view(az,el);
                event=0;
                ProjectionDropDown_3ValueChanged(app, event)
                AxisDropDown_3ValueChanged(app, event)
                LightDropDown_3ValueChanged(app, event)
                ShadingDropDown_3ValueChanged(app, event)            
            end

        end

        % Value changed function: ProjectionDropDown_3
        function ProjectionDropDown_3ValueChanged(app, event)
            value = app.ProjectionDropDown_3.Value;
            if isvalid(app.skeleton_h_axes)
              my_type=get(app.skeleton_h_axes, 'type');
              if strcmp(my_type,'axes')
                  camproj(app.skeleton_h_axes,value)
              end
            end
        end

        % Value changed function: AxisDropDown_3
        function AxisDropDown_3ValueChanged(app, event)
            value = app.AxisDropDown_3.Value;
            if isvalid(app.skeleton_h_axes)
              my_type=get(app.skeleton_h_axes, 'type');
              if strcmp(my_type,'axes')
                  if strcmp(value,'off')
                       axis(app.skeleton_h_axes,'off')
                  elseif strcmp(value,'grid on')
                       axis(app.skeleton_h_axes,'on')
                       grid(app.skeleton_h_axes,'on')
                  elseif strcmp(value,'grid off')
                       axis(app.skeleton_h_axes,'on')
                       grid(app.skeleton_h_axes,'off')
                  end
              end
            end 
        end

        % Value changed function: LightDropDown_3
        function LightDropDown_3ValueChanged(app, event)
            value = app.LightDropDown_3.Value;
            if isvalid(app.skeleton_h_axes)
              my_type=get(app.skeleton_h_axes, 'type');
              if strcmp(my_type,'axes')
                  if strcmp(value,'head')
                       ttt = findobj(app.skeleton_h_figure,'Type','Light');
                       delete(ttt);
                       camlight(app.skeleton_h_axes,'headlight');
                  elseif strcmp(value,'left')
                       ttt = findobj(app.skeleton_h_figure,'Type','Light');
                       delete(ttt);
                       camlight(app.skeleton_h_axes,'left');
                  elseif strcmp(value,'right')
                       ttt = findobj(app.skeleton_h_figure,'Type','Light');
                       delete(ttt);
                       camlight(app.skeleton_h_axes,'right'),
                  elseif strcmp(value,'off')
                       ttt = findobj(app.skeleton_h_figure,'Type','Light');
                       delete(ttt);
                  end
              end
            end             
        end

        % Value changed function: showpathCheckBox
        function showpathCheckBoxValueChanged(app, event)
            value = app.showpathCheckBox.Value;
%            if strcmp(app.skeleton_filename,'')
%            else
                 if app.showpathCheckBox.Value==1
                      app.skeletonEditField.Value=[app.skeleton_path app.skeleton_filename];
                 else
                      app.skeletonEditField.Value=app.skeleton_filename;
                 end   
%            end
        end

        % Value changing function: skeletonEditField
        function skeletonEditFieldValueChanging(app, event)
            changingValue = event.Value;
            showpathCheckBoxValueChanged(app, event)
        end

        % Value changed function: ShadingDropDown_3
        function ShadingDropDown_3ValueChanged(app, event)
            value = app.ShadingDropDown_3.Value;
             if isvalid(app.skeleton_h_axes)
              my_type=get(app.skeleton_h_axes, 'type');
              if strcmp(my_type,'axes')
                  shading(app.skeleton_h_axes,value)
%                  if strcmp(value,'flat')
%                       shading(app.skeleton_h_axes,'off')
%                  elseif strcmp(value,'grid on')
%                       axis(app.skeleton_h_axes,'on')
%                       grid(app.skeleton_h_axes,'on')
%                  elseif strcmp(value,'grid off')
%                       axis(app.skeleton_h_axes,'on')
%                       grid(app.skeleton_h_axes,'off')
%                  end
              end
            end 
        end

        % Value changing function: Slider_6
        function Slider_6ValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: ResolutionEditField_2
        function ResolutionEditField_2ValueChanged(app, event)
            value = app.ResolutionEditField_2.Value;
            app.ResolutionEditField_2.Value=max(4,min(40,round(app.ResolutionEditField_2.Value)));
            app.Slider_6.Value=round(app.ResolutionEditField_2.Value);
            if length(app.g_sk_Points)>0
                 if isvalid(app.skeleton_h_axes)
                 else
                     app.skeleton_h_figure=figure('Visible','off');
                     app.skeleton_h_axes=axes;
                     view(50,20)
                 end
                [az,el] = view(app.skeleton_h_axes);
                [app.g_sk_tri_flat_tot,app.g_sk_F_x,app.g_sk_F_y,app.g_sk_F_z,app.g_sk_COL]=load_flattri_skeleton(app.g_sk_Points,app.g_sk_Tri,app.g_sk_n_tri,app.Slider_6.Value)

                refresh_geometry(app.g_sk_tri_flat_tot,app.g_sk_F_x,app.g_sk_F_y,app.g_sk_F_z,app.g_sk_COL,[],[],[],app.skeleton_h_axes,app.ColorDropDown_3.Value,app.RotationaxisDropDown.Value,app.ErrorDropDown.Value,app.ShowErrorCheckBox.Value,1)               
%refresh_geometry(app.g_sk_tri_flat_tot,app.g_sk_F_x,app.g_sk_F_y,app.g_sk_F_z,app.g_sk_COL,app.skeleton_h_axes,app.ColorDropDown_3.Value,app.RotationaxisDropDown.Value)               

%               plot_go3(file,app.ssurf_h_axes,app.Slider_7.Value,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
                set(app.skeleton_h_figure, 'Visible','on')
                rotate3d(app.skeleton_h_axes,'on')
                axis(app.skeleton_h_axes,'equal')
                view(az,el);
                event=0;
                ProjectionDropDown_3ValueChanged(app, event)
                AxisDropDown_3ValueChanged(app, event)
                LightDropDown_3ValueChanged(app, event)
                ShadingDropDown_3ValueChanged(app, event)            
            end            
        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)
            value = app.Slider_6.Value;
            app.Slider_6.Value=round(app.Slider_6.Value);
            app.ResolutionEditField_2.Value=round(app.Slider_6.Value);
            if length(app.g_sk_Points)>0
                 if isvalid(app.skeleton_h_axes)
                 else
                     app.skeleton_h_figure=figure('Visible','off');
                     app.skeleton_h_axes=axes;
                     view(50,20)
                 end
                [az,el] = view(app.skeleton_h_axes);
                [app.g_sk_tri_flat_tot,app.g_sk_F_x,app.g_sk_F_y,app.g_sk_F_z,app.g_sk_COL]=load_flattri_skeleton(app.g_sk_Points,app.g_sk_Tri,app.g_sk_n_tri,app.Slider_6.Value);

                refresh_geometry(app.g_sk_tri_flat_tot,app.g_sk_F_x,app.g_sk_F_y,app.g_sk_F_z,app.g_sk_COL,[],[],[],app.skeleton_h_axes,app.ColorDropDown_3.Value,app.RotationaxisDropDown.Value,app.ErrorDropDown.Value,app.ShowErrorCheckBox.Value,1)               
                %refresh_geometry(app.g_sk_tri_flat_tot,app.g_sk_F_x,app.g_sk_F_y,app.g_sk_F_z,app.g_sk_COL,app.skeleton_h_axes,app.ColorDropDown_3.Value,app.RotationaxisDropDown.Value)               

%               plot_go3(file,app.ssurf_h_axes,app.Slider_7.Value,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
                set(app.skeleton_h_figure, 'Visible','on')
                rotate3d(app.skeleton_h_axes,'on')
                axis(app.skeleton_h_axes,'equal')
                view(az,el);
                event=0;
                ProjectionDropDown_3ValueChanged(app, event)
                AxisDropDown_3ValueChanged(app, event)
                LightDropDown_3ValueChanged(app, event)
                ShadingDropDown_3ValueChanged(app, event)            
            end

        end

        % Value changed function: RotationaxisDropDown
        function RotationaxisDropDownValueChanged(app, event)
            value = app.RotationaxisDropDown.Value;
            update_plot_skeleton(app)
            
            
            value = app.RotationaxisDropDown.Value;
            if length(app.g_sk_F_x)>0
                 if isvalid(app.skeleton_h_axes)
                 else
                     app.skeleton_h_figure=figure('Visible','off');
                     app.skeleton_h_axes=axes;
                     view(50,20)
                 end
                [az,el] = view(app.skeleton_h_axes);
%                [app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL]=load_flattri_smooth_geometry(app.g_sm_ntri,app.g_sm_norder,app.g_sm_npoints,app.g_sm_srcvals,app.Slider_7.Value)

                refresh_geometry(app.g_sk_tri_flat_tot,app.g_sk_F_x,app.g_sk_F_y,app.g_sk_F_z,app.g_sk_COL,[],[],[],app.skeleton_h_axes,app.ColorDropDown_3.Value,app.RotationaxisDropDown.Value,app.ErrorDropDown.Value,app.ShowErrorCheckBox.Value,1)               
%refresh_geometry(app.g_sk_tri_flat_tot,app.g_sk_F_x,app.g_sk_F_y,app.g_sk_F_z,app.g_sk_COL,app.skeleton_h_axes,app.ColorDropDown_3.Value,app.RotationaxisDropDown.Value)               

%               plot_go3(file,app.ssurf_h_axes,app.Slider_7.Value,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
                set(app.skeleton_h_figure, 'Visible','on')
                rotate3d(app.skeleton_h_axes,'on')
                axis(app.skeleton_h_axes,'equal')
                view(az,el);
                event=0;
                ProjectionDropDown_3ValueChanged(app, event)
                AxisDropDown_3ValueChanged(app, event)
                LightDropDown_3ValueChanged(app, event)
                ShadingDropDown_3ValueChanged(app, event)            
            end
        end

        % Callback function
        function searchmshdefaultfolderButtonPushed(app, event)
            if isfolder(app.skeleton_path)
                selpath = uigetdir(app.skeleton_path);
                if ischar(selpath)
                    app.skeleton_path=selpath;
                    app.go3fileEditField.Value=app.skeleton_path
                end
            else
               selpath = uigetdir;
                if ischar(selpath)
                    app.skeleton_path=selpath;
                    app.go3fileEditField.Value=app.skeleton_path
                end
            end
        end

        % Callback function
        function EditField_2ValueChanging(app, event)
            changingValue = event.Value;
        end

        % Callback function
        function EditField_2ValueChanged(app, event)
            value = app.go3fileEditField.Value;
            'dentro'
            value
            isfolder(value)
            if isfolder(value)
                app.skeleton_path=app.go3fileEditField.Value
            else
                app.go3fileEditField.Value=app.skeleton_path
            end
        end

        % Callback function
        function searchgo3defaultfolderButtonPushed(app, event)
            if isfolder(app.ssurf_path)
                selpath = uigetdir(app.ssurf_path);
                if ischar(selpath)
                    app.ssurf_path=selpath;
                    app.EditField_3.Value=app.ssurf_path
                end
            else
               selpath = uigetdir;
                if ischar(selpath)
                    app.ssurf_path=selpath;
                    app.EditField_3.Value=app.ssurf_path
                end
            end
        end

        % Callback function
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            if isfolder(value)
                app.ssurf_path=app.EditField_3.Value
            else
                app.EditField_3.Value=app.ssurf_path
            end

        end

        % Button pushed function: Opengo3Button
        function Opengo3ButtonPushed(app, event)

            [file,path,indx] = uigetfile([app.ssurf_path '*.go3']);  
            if isequal(file,0)
            else
                [cx,cy]=size(app.my_cell);
                app.my_cell{cx+1,1}=file;
                app.my_cell{cx+1,2}=1;
                app.TransparencyEditField.Value=1;
                app.TransparencySlider.Value=1;

                app.ListListBox.Items{length(app.ListListBox.Items)+1}=file;
                app.ListListBox.Value=file;
                [app.g_sm_ntri{cx+1},app.g_sm_norder{cx+1},app.g_sm_npoints{cx+1},app.g_sm_srcvals{cx+1}]=open_gov3([path file]);
                app.ssurf_filename=file;
                app.ssurf_path=path;
%                 plot_ssurf(app,[path file])
                 if app.showpathCheckBox_2.Value==1
                      app.go3fileEditField.Value=[path file];
                 else
                      app.go3fileEditField.Value=file;
                 end
                 
                 if isvalid(app.ssurf_h_axes)
                 else
                     app.ssurf_h_figure=figure('Visible','off');
                     app.ssurf_h_axes=axes;
                     view(50,20)
                 end
                [az,el] = view(app.ssurf_h_axes);
                [app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.g_sm_L2,app.g_sm_L1,app.g_sm_Lsup]=load_flattri_smooth_geometry_cell(app.g_sm_ntri,app.g_sm_norder,app.g_sm_npoints,app.g_sm_srcvals,app.Slider_7.Value)
                
                refresh_geometry_cell(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.g_sm_L2,app.g_sm_L1,app.g_sm_Lsup,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value,app.ErrorDropDown.Value,app.ShowErrorCheckBox.Value,app.my_cell(:,2))                
%refresh_geometry(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)

%               plot_go3(file,app.ssurf_h_axes,app.Slider_7.Value,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
                set(app.ssurf_h_figure, 'Visible','on')
                rotate3d(app.ssurf_h_axes,'on')
                axis(app.ssurf_h_axes,'equal')
                view(az,el);
                event=0;
                ProjectionDropDown_4ValueChanged(app, event)
                AxisDropDown_4ValueChanged(app, event)
                LightDropDown_4ValueChanged(app, event)
                ShadingDropDown_4ValueChanged(app, event)          
            end  
            
        end

        % Value changed function: AxisDropDown_4
        function AxisDropDown_4ValueChanged(app, event)
            value = app.AxisDropDown_4.Value;
            if isvalid(app.ssurf_h_axes)
              my_type=get(app.ssurf_h_axes, 'type');
              if strcmp(my_type,'axes')
                  if strcmp(value,'off')
                       axis(app.ssurf_h_axes,'off')
                  elseif strcmp(value,'grid on')
                       axis(app.ssurf_h_axes,'on')
                       grid(app.ssurf_h_axes,'on')
                  elseif strcmp(value,'grid off')
                       axis(app.ssurf_h_axes,'on')
                       grid(app.ssurf_h_axes,'off')
                  end
              end
            end
        end

        % Value changed function: LightDropDown_4
        function LightDropDown_4ValueChanged(app, event)
            value = app.LightDropDown_4.Value;
            if isvalid(app.ssurf_h_axes)
              my_type=get(app.ssurf_h_axes, 'type');
              if strcmp(my_type,'axes')
                  if strcmp(value,'head')
                       ttt = findobj(app.ssurf_h_figure,'Type','Light');
                       delete(ttt);
                       camlight(app.ssurf_h_axes,'headlight');
                  elseif strcmp(value,'left')
                       ttt = findobj(app.ssurf_h_figure,'Type','Light');
                       delete(ttt);
                       camlight(app.ssurf_h_axes,'left');
                  elseif strcmp(value,'right')
                       ttt = findobj(app.ssurf_h_figure,'Type','Light');
                       delete(ttt);
                       camlight(app.ssurf_h_axes,'right'),
                  elseif strcmp(value,'off')
                       ttt = findobj(app.ssurf_h_figure,'Type','Light');
                       delete(ttt);
                  end
              end
            end     
            
        end

        % Value changed function: Slider_7
        function Slider_7ValueChanged(app, event)
            value = app.Slider_7.Value;
            app.Slider_7.Value=round(app.Slider_7.Value);
            app.ResolutionEditField_3.Value=round(app.Slider_7.Value);
            if length(app.g_sm_srcvals)>0
                 if isvalid(app.ssurf_h_axes)
                 else
                     app.ssurf_h_figure=figure('Visible','off');
                     app.ssurf_h_axes=axes;
                     view(50,20)
                 end
                [az,el] = view(app.ssurf_h_axes);
                [app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.g_sm_L2,app.g_sm_L1,app.g_sm_Lsup]=load_flattri_smooth_geometry_cell(app.g_sm_ntri,app.g_sm_norder,app.g_sm_npoints,app.g_sm_srcvals,app.Slider_7.Value)
                refresh_geometry_cell(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.g_sm_L2,app.g_sm_L1,app.g_sm_Lsup,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value,app.ErrorDropDown.Value,app.ShowErrorCheckBox.Value,app.my_cell(:,2))                
%refresh_geometry(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)               
%               plot_go3(file,app.ssurf_h_axes,app.Slider_7.Value,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
                set(app.ssurf_h_figure, 'Visible','on')
                rotate3d(app.ssurf_h_axes,'on')
                axis(app.ssurf_h_axes,'equal')
                view(az,el);
                event=0;
                ProjectionDropDown_4ValueChanged(app, event)
                AxisDropDown_4ValueChanged(app, event)
                LightDropDown_4ValueChanged(app, event)
                ShadingDropDown_4ValueChanged(app, event)            
            end

            
        end

        % Value changed function: RotationaxisDropDown_2
        function RotationaxisDropDown_2ValueChanged(app, event)
            value = app.RotationaxisDropDown_2.Value;
            if length(app.g_sm_F_x)>0
                 if isvalid(app.ssurf_h_axes)
                 else
                     app.ssurf_h_figure=figure('Visible','off');
                     app.ssurf_h_axes=axes;
                     view(50,20)
                 end
                [az,el] = view(app.ssurf_h_axes);
%                [app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL]=load_flattri_smooth_geometry(app.g_sm_ntri,app.g_sm_norder,app.g_sm_npoints,app.g_sm_srcvals,app.Slider_7.Value)
                refresh_geometry_cell(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.g_sm_L2,app.g_sm_L1,app.g_sm_Lsup,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value,app.ErrorDropDown.Value,app.ShowErrorCheckBox.Value,app.my_cell(:,2))
                
%refresh_geometry(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)               
%               plot_go3(file,app.ssurf_h_axes,app.Slider_7.Value,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
                set(app.ssurf_h_figure, 'Visible','on')
                rotate3d(app.ssurf_h_axes,'on')
                axis(app.ssurf_h_axes,'equal')
                view(az,el);
                event=0;
                ProjectionDropDown_4ValueChanged(app, event)
                AxisDropDown_4ValueChanged(app, event)
                LightDropDown_4ValueChanged(app, event)
                ShadingDropDown_4ValueChanged(app, event)            
            end
        end

        % Value changed function: ColorDropDown_4
        function ColorDropDown_4ValueChanged(app, event)
            value = app.ColorDropDown_4.Value;
            
            if length(app.g_sm_F_x)>0
                 if isvalid(app.ssurf_h_axes)
                 else
                     app.ssurf_h_figure=figure('Visible','off');
                     app.ssurf_h_axes=axes;
                     view(50,20)
                 end
                [az,el] = view(app.ssurf_h_axes);
%                [app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL]=load_flattri_smooth_geometry(app.g_sm_ntri,app.g_sm_norder,app.g_sm_npoints,app.g_sm_srcvals,app.Slider_7.Value)
                refresh_geometry_cell(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.g_sm_L2,app.g_sm_L1,app.g_sm_Lsup,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value,app.ErrorDropDown.Value,app.ShowErrorCheckBox.Value,app.my_cell(:,2))                

%refresh_geometry(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
%               plot_go3(file,app.ssurf_h_axes,app.Slider_7.Value,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
                set(app.ssurf_h_figure, 'Visible','on')
                rotate3d(app.ssurf_h_axes,'on')
                axis(app.ssurf_h_axes,'equal')
                view(az,el);
                event=0;
                ProjectionDropDown_4ValueChanged(app, event)
                AxisDropDown_4ValueChanged(app, event)
                LightDropDown_4ValueChanged(app, event)
                ShadingDropDown_4ValueChanged(app, event)            
            end
        end

        % Value changed function: ShadingDropDown_4
        function ShadingDropDown_4ValueChanged(app, event)
            value = app.ShadingDropDown_4.Value;
             if isvalid(app.ssurf_h_axes)
              my_type=get(app.ssurf_h_axes, 'type');
              if strcmp(my_type,'axes')
                  shading(app.ssurf_h_axes,value)
%                  if strcmp(value,'flat')
%                       shading(app.skeleton_h_axes,'off')
%                  elseif strcmp(value,'grid on')
%                       axis(app.skeleton_h_axes,'on')
%                       grid(app.skeleton_h_axes,'on')
%                  elseif strcmp(value,'grid off')
%                       axis(app.skeleton_h_axes,'on')
%                       grid(app.skeleton_h_axes,'off')
%                  end
              end
            end 
        end

        % Value changed function: ProjectionDropDown_4
        function ProjectionDropDown_4ValueChanged(app, event)
            value = app.ProjectionDropDown_4.Value;
            if isvalid(app.ssurf_h_axes)
              my_type=get(app.ssurf_h_axes, 'type');
              if strcmp(my_type,'axes')
                  camproj(app.ssurf_h_axes,value)
              end
            end
        end

        % Value changed function: showpathCheckBox_2
        function showpathCheckBox_2ValueChanged(app, event)
            value = app.showpathCheckBox_2.Value;
                 if app.showpathCheckBox_2.Value==1
                      app.go3fileEditField.Value=[app.ssurf_path app.ssurf_filename];
                 else
                      app.go3fileEditField.Value=app.ssurf_filename;
                 end
        end

        % Value changing function: go3fileEditField
        function go3fileEditFieldValueChanging(app, event)
            changingValue = event.Value;
            showpathCheckBox_2ValueChanged(app, event);
        end

        % Value changed function: SmoothingparameterEditField
        function SmoothingparameterEditFieldValueChanged(app, event)
            value = app.SmoothingparameterEditField.Value;
            app.SmoothingparameterEditField.Value=max(4,min(40,app.SmoothingparameterEditField.Value));
            app.Slider_5.Value=round(app.SmoothingparameterEditField.Value);            
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            value = app.Slider_5.Value;                        
            app.Slider_5.Value=app.Slider_5.Value;
            app.SmoothingparameterEditField.Value=app.Slider_5.Value;            
        end

        % Button pushed function: RunSmootherButton
        function RunSmootherButtonPushed(app, event)
            line1=num2str(app.OrderofthequadratureruleEditField.Value);
            line2=num2str(app.OrderofthesmoothsurfaceEditField.Value);
            if strcmp(app.AdaptiveflagDropDown.Value,'Non adaptive')
                line3=num2str(0);
            elseif strcmp(app.AdaptiveflagDropDown.Value,'Adaptive')
                line3=num2str(1);
            end   
            line4=app.NumberofrefinemensDropDown.Value;
            line5=app.Nameofthego3fileEditField.Value;
            line6=num2str(app.SmoothingparameterEditField.Value); 
            rel_path = relativepath(app.skeleton_path);
            line7=[rel_path app.skeleton_filename];
            string2store=[line1 '\n' line2 '\n' line3 '\n' line4 '\n' line5 '\n' line6  '\n' line7 '\n']
            if isfile('auxfile_inout_matlab_fortran.txt')
                delete 'auxfile_inout_matlab_fortran.txt'
            end
            fileID=fopen('auxfile_inout_matlab_fortran.txt','w');
            fprintf(fileID,string2store);
            clc
            diary on
            system('make -f makefile_matlab')
            diary off;
            output = fileread('diary');
            app.TextArea.Value=output; % fill in 'textbox_handle' with the actual handle for the textbox
            drawnow;
        end

        % Value changed function: ResolutionEditField_3
        function ResolutionEditField_3ValueChanged(app, event)
            value = app.ResolutionEditField_3.Value;
            app.ResolutionEditField_3.Value=max(4,min(40,round(app.ResolutionEditField_3.Value)));
            app.Slider_7.Value=round(app.ResolutionEditField_3.Value);  
            if length(app.g_sm_srcvals)>0
                 if isvalid(app.ssurf_h_axes)
                 else
                     app.ssurf_h_figure=figure('Visible','off');
                     app.ssurf_h_axes=axes;
                     view(50,20)
                 end
                [az,el] = view(app.ssurf_h_axes);
                [app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.g_sm_L2,app.g_sm_L1,app.g_sm_Lsup]=load_flattri_smooth_geometry_cell(app.g_sm_ntri,app.g_sm_norder,app.g_sm_npoints,app.g_sm_srcvals,app.Slider_7.Value)
                refresh_geometry_cell(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.g_sm_L2,app.g_sm_L1,app.g_sm_Lsup,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value,app.ErrorDropDown.Value,app.ShowErrorCheckBox.Value,app.my_cell(:,2))                
%refresh_geometry(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
%               plot_go3(file,app.ssurf_h_axes,app.Slider_7.Value,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
                set(app.ssurf_h_figure, 'Visible','on')
                rotate3d(app.ssurf_h_axes,'on')
                axis(app.ssurf_h_axes,'equal')
                view(az,el);
                event=0;
                ProjectionDropDown_4ValueChanged(app, event)
                AxisDropDown_4ValueChanged(app, event)
                LightDropDown_4ValueChanged(app, event)
                ShadingDropDown_4ValueChanged(app, event)            
            end

        end

        % Callback function
        function Slider_8ValueChanged(app, event)
          

        end

        % Callback function
        function Slider_9ValueChanged(app, event)
      
        end

        % Callback function
        function Slider_10ValueChanged(app, event)
          

        end

        % Callback function
        function x0EditFieldValueChanged(app, event)


        end

        % Callback function
        function y0EditFieldValueChanged(app, event)
           
        end

        % Callback function
        function z0EditFieldValueChanged(app, event)


        end

        % Value changed function: ShowErrorCheckBox
        function ShowErrorCheckBoxValueChanged(app, event)
            value = app.ShowErrorCheckBox.Value
            if value 
                app.ErrorDropDown.Enable=1;
                app.ColorDropDown_4.Enable=0;
            else
                app.ErrorDropDown.Enable=0;
                app.ColorDropDown_4.Enable=1;                 
            end
            
            if length(app.g_sm_F_x)>0
                 if isvalid(app.ssurf_h_axes)
                 else
                     app.ssurf_h_figure=figure('Visible','off');
                     app.ssurf_h_axes=axes;
                     view(50,20)
                 end
                [az,el] = view(app.ssurf_h_axes);
%                [app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL]=load_flattri_smooth_geometry(app.g_sm_ntri,app.g_sm_norder,app.g_sm_npoints,app.g_sm_srcvals,app.Slider_7.Value)
                refresh_geometry_cell(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.g_sm_L2,app.g_sm_L1,app.g_sm_Lsup,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value,app.ErrorDropDown.Value,app.ShowErrorCheckBox.Value,app.my_cell(:,2))                

%refresh_geometry(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
%               plot_go3(file,app.ssurf_h_axes,app.Slider_7.Value,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
                set(app.ssurf_h_figure, 'Visible','on')
                rotate3d(app.ssurf_h_axes,'on')
                axis(app.ssurf_h_axes,'equal')
                view(az,el);
                event=0;
                ProjectionDropDown_4ValueChanged(app, event)
                AxisDropDown_4ValueChanged(app, event)
                LightDropDown_4ValueChanged(app, event)
                ShadingDropDown_4ValueChanged(app, event)            
            end

        end

        % Value changed function: ErrorDropDown
        function ErrorDropDownValueChanged(app, event)
            value = app.ErrorDropDown.Value;
            
            if length(app.g_sm_F_x)>0
                 if isvalid(app.ssurf_h_axes)
                 else
                     app.ssurf_h_figure=figure('Visible','off');
                     app.ssurf_h_axes=axes;
                     view(50,20)
                 end
                [az,el] = view(app.ssurf_h_axes);
%                [app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL]=load_flattri_smooth_geometry(app.g_sm_ntri,app.g_sm_norder,app.g_sm_npoints,app.g_sm_srcvals,app.Slider_7.Value)
                refresh_geometry_cell(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.g_sm_L2,app.g_sm_L1,app.g_sm_Lsup,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value,app.ErrorDropDown.Value,app.ShowErrorCheckBox.Value,app.my_cell(:,2))                

%refresh_geometry(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
%               plot_go3(file,app.ssurf_h_axes,app.Slider_7.Value,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
                set(app.ssurf_h_figure, 'Visible','on')
                rotate3d(app.ssurf_h_axes,'on')
                axis(app.ssurf_h_axes,'equal')
                view(az,el);
                event=0;
                ProjectionDropDown_4ValueChanged(app, event)
                AxisDropDown_4ValueChanged(app, event)
                LightDropDown_4ValueChanged(app, event)
                ShadingDropDown_4ValueChanged(app, event)            
            end

            
        end

        % Button pushed function: PrepareButton
        function PrepareButtonPushed(app, event)
            line1=num2str(app.OrderofthequadratureruleEditField.Value);
            line2=num2str(app.OrderofthesmoothsurfaceEditField.Value);
            if strcmp(app.AdaptiveflagDropDown.Value,'Non adaptive')
                line3=num2str(0);
            elseif strcmp(app.AdaptiveflagDropDown.Value,'Adaptive')
                line3=num2str(1);
            end   
            line4=app.NumberofrefinemensDropDown.Value;
            line5=app.Nameofthego3fileEditField.Value;
            line6=num2str(app.SmoothingparameterEditField.Value); 
            rel_path = relativepath(app.skeleton_path);
            line7=[rel_path app.skeleton_filename];
            string2store=[line1 '\n' line2 '\n' line3 '\n' line4 '\n' line5 '\n' line6  '\n' line7 '\n']
            if isfile('auxfile_inout_matlab_fortran.txt')
                delete 'auxfile_inout_matlab_fortran.txt'
            end
            fileID=fopen('auxfile_inout_matlab_fortran.txt','w');
            fprintf(fileID,string2store);
            clc
            app.TextArea.Value=string2store; % fill in 'textbox_handle' with the actual handle for the textbox
            drawnow;
        end

        % Value changed function: TransparencySlider
        function TransparencySliderValueChanged(app, event)
            value = app.TransparencySlider.Value;
            app.TransparencySlider.Value=round(app.TransparencySlider.Value*10)/10;
            app.TransparencyEditField.Value=round(app.TransparencySlider.Value*10)/10;


            [cx,cy]=size(app.my_cell);
            encontrado=0;
            buscado=app.ListListBox.Value
            for count=1:cx
                app.my_cell{count,1}
                app.my_cell{count,2}
%                app.ListListBox.Value
                if strcmp(app.my_cell{count,1},app.ListListBox.Value)
                  encontrado=count
                end
            end
            app.my_cell{encontrado,2}=app.TransparencySlider.Value


             if length(app.g_sm_F_x)>0
                 if isvalid(app.ssurf_h_axes)
                 else
                     app.ssurf_h_figure=figure('Visible','off');
                     app.ssurf_h_axes=axes;
                     view(50,20)
                 end
                [az,el] = view(app.ssurf_h_axes);
%                [app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL]=load_flattri_smooth_geometry(app.g_sm_ntri,app.g_sm_norder,app.g_sm_npoints,app.g_sm_srcvals,app.Slider_7.Value)
                refresh_geometry_cell(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.g_sm_L2,app.g_sm_L1,app.g_sm_Lsup,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value,app.ErrorDropDown.Value,app.ShowErrorCheckBox.Value,app.my_cell(:,2))
                
%refresh_geometry(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)               
%               plot_go3(file,app.ssurf_h_axes,app.Slider_7.Value,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
                set(app.ssurf_h_figure, 'Visible','on')
                rotate3d(app.ssurf_h_axes,'on')
                axis(app.ssurf_h_axes,'equal')
                view(az,el);
                event=0;
                ProjectionDropDown_4ValueChanged(app, event)
                AxisDropDown_4ValueChanged(app, event)
                LightDropDown_4ValueChanged(app, event)
                ShadingDropDown_4ValueChanged(app, event)            
            end
            
        end

        % Value changed function: TransparencyEditField
        function TransparencyEditFieldValueChanged(app, event)
            value = app.TransparencyEditField.Value;
            app.TransparencyEditField.Value=max(0,min(1,round(app.TransparencyEditField.Value*10)/10));
            app.TransparencySlider.Value=round(app.TransparencyEditField.Value*10)/10;
            
            
            [cx,cy]=size(app.my_cell);
            encontrado=0;
            buscado=app.ListListBox.Value
            for count=1:cx
                app.my_cell{count,1}
                app.my_cell{count,2}
%                app.ListListBox.Value
                if strcmp(app.my_cell{count,1},app.ListListBox.Value)
                  encontrado=count
                end
            end
            app.my_cell{encontrado,2}=app.TransparencySlider.Value
            
            
            if length(app.g_sm_F_x)>0
                 if isvalid(app.ssurf_h_axes)
                 else
                     app.ssurf_h_figure=figure('Visible','off');
                     app.ssurf_h_axes=axes;
                     view(50,20)
                 end
                [az,el] = view(app.ssurf_h_axes);
%                [app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL]=load_flattri_smooth_geometry(app.g_sm_ntri,app.g_sm_norder,app.g_sm_npoints,app.g_sm_srcvals,app.Slider_7.Value)
                refresh_geometry_cell(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.g_sm_L2,app.g_sm_L1,app.g_sm_Lsup,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value,app.ErrorDropDown.Value,app.ShowErrorCheckBox.Value,app.my_cell(:,2))
                
%refresh_geometry(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)               
%               plot_go3(file,app.ssurf_h_axes,app.Slider_7.Value,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
                set(app.ssurf_h_figure, 'Visible','on')
                rotate3d(app.ssurf_h_axes,'on')
                axis(app.ssurf_h_axes,'equal')
                view(az,el);
                event=0;
                ProjectionDropDown_4ValueChanged(app, event)
                AxisDropDown_4ValueChanged(app, event)
                LightDropDown_4ValueChanged(app, event)
                ShadingDropDown_4ValueChanged(app, event)            
            end
            
        end

        % Button pushed function: CheckIntersButton
        function CheckIntersButtonPushed(app, event)
            [cx,cy]=size(app.my_cell);
            for count=1:cx
                app.my_cell{count,2}=0.2;
            end
            app.TransparencySlider.Value=0.2;
            app.TransparencyEditField.Value=0.2;
            
            app.ColorDropDown_4.Value='uniform';
            
            
              if length(app.g_sm_F_x)>0
                 if isvalid(app.ssurf_h_axes)
                 else
                     app.ssurf_h_figure=figure('Visible','off');
                     app.ssurf_h_axes=axes;
                     view(50,20)
                 end
                [az,el] = view(app.ssurf_h_axes);
%                [app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL]=load_flattri_smooth_geometry(app.g_sm_ntri,app.g_sm_norder,app.g_sm_npoints,app.g_sm_srcvals,app.Slider_7.Value)
                refresh_geometry_cell(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.g_sm_L2,app.g_sm_L1,app.g_sm_Lsup,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value,app.ErrorDropDown.Value,app.ShowErrorCheckBox.Value,app.my_cell(:,2))
                
%refresh_geometry(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)               
%               plot_go3(file,app.ssurf_h_axes,app.Slider_7.Value,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
                set(app.ssurf_h_figure, 'Visible','on')
                rotate3d(app.ssurf_h_axes,'on')
                axis(app.ssurf_h_axes,'equal')
                view(az,el);
                event=0;
                ProjectionDropDown_4ValueChanged(app, event)
                AxisDropDown_4ValueChanged(app, event)
                LightDropDown_4ValueChanged(app, event)
                ShadingDropDown_4ValueChanged(app, event)            
            end
        end

        % Button pushed function: RemovefromlistButton
        function RemovefromlistButtonPushed(app, event)
            [cx,cy]=size(app.my_cell)
            if cx==1
                app.my_cell=cell(0,0);
            else
                eliminame=0;
                for count=1:cx
                    app.my_cell{count,1}
                    app.ListListBox.Value
                    if strcmp(app.my_cell{count,1},app.ListListBox.Value)
                      eliminame=count
                    end
                end
                if eliminame>0
                    if (eliminame==1)
                        app.my_cell=app.my_cell([2:cx],:);
                        app.g_sm_ntri=app.g_sm_ntri([2:cx]);
                        app.g_sm_norder=app.g_sm_norder([2:cx]);
                        app.g_sm_npoints=app.g_sm_npoints([2:cx]);
                        app.g_sm_srcvals=app.g_sm_srcvals([2:cx]);
                        app.g_sm_tri_flat_tot=app.g_sm_tri_flat_tot([2:cx]);
                        app.g_sm_F_x=app.g_sm_F_x([2:cx]);
                        app.g_sm_F_y=app.g_sm_F_y([2:cx]);
                        app.g_sm_F_z=app.g_sm_F_z([2:cx]);
                        app.g_sm_COL=app.g_sm_COL([2:cx]);
                        app.g_sm_L2=app.g_sm_L2([2:cx]);
                        app.g_sm_L1=app.g_sm_L1([2:cx]);
                        app.g_sm_Lsup=app.g_sm_Lsup([2:cx]);
                    elseif (eliminame==cx)
                        app.my_cell=app.my_cell([1:cx-1],:);
                        app.g_sm_ntri=app.g_sm_ntri([1:cx-1]);
                        app.g_sm_norder=app.g_sm_norder([1:cx-1]);
                        app.g_sm_npoints=app.g_sm_npoints([1:cx-1]);
                        app.g_sm_srcvals=app.g_sm_srcvals([1:cx-1]);
                        app.g_sm_tri_flat_tot=app.g_sm_tri_flat_tot([1:cx-1]);
                        app.g_sm_F_x=app.g_sm_F_x([1:cx-1]);
                        app.g_sm_F_y=app.g_sm_F_y([1:cx-1]);
                        app.g_sm_F_z=app.g_sm_F_z([1:cx-1]);
                        app.g_sm_COL=app.g_sm_COL([1:cx-1]);
                        app.g_sm_L2=app.g_sm_L2([1:cx-1]);
                        app.g_sm_L1=app.g_sm_L1([1:cx-1]);
                        app.g_sm_Lsup=app.g_sm_Lsup([1:cx-1]);                      
                    else
                        app.my_cell=app.my_cell([1:eliminame-1 eliminame+1:cx],:);
                        app.g_sm_ntri=app.g_sm_ntri([1:eliminame-1 eliminame+1:cx]);
                        app.g_sm_norder=app.g_sm_norder([1:eliminame-1 eliminame+1:cx]);
                        app.g_sm_npoints=app.g_sm_npoints([1:eliminame-1 eliminame+1:cx]);
                        app.g_sm_srcvals=app.g_sm_srcvals([1:eliminame-1 eliminame+1:cx]);
                        app.g_sm_tri_flat_tot=app.g_sm_tri_flat_tot([1:eliminame-1 eliminame+1:cx]);
                        app.g_sm_F_x=app.g_sm_F_x([1:eliminame-1 eliminame+1:cx]);
                        app.g_sm_F_y=app.g_sm_F_y([1:eliminame-1 eliminame+1:cx]);
                        app.g_sm_F_z=app.g_sm_F_z([1:eliminame-1 eliminame+1:cx]);
                        app.g_sm_COL=app.g_sm_COL([1:eliminame-1 eliminame+1:cx]);
                        app.g_sm_L2=app.g_sm_L2([1:eliminame-1 eliminame+1:cx]);
                        app.g_sm_L1=app.g_sm_L1([1:eliminame-1 eliminame+1:cx]);
                        app.g_sm_Lsup=app.g_sm_Lsup([1:eliminame-1 eliminame+1:cx]);
                    end
                end
            end
            app.ListListBox.Items=cell(0);
            for count=1:cx-1
                app.ListListBox.Items{count}=app.my_cell{count,1};
            end
            if cx>1
              app.ListListBox.Value=app.my_cell{1,1};
              app.TransparencySlider.Value=app.my_cell{1,2};
              app.TransparencyEditField.Value=app.my_cell{1,2};
            end
            
            if isvalid(app.ssurf_h_axes)
            else
                app.ssurf_h_figure=figure('Visible','off');
                app.ssurf_h_axes=axes;
                view(50,20)
            end
            [az,el] = view(app.ssurf_h_axes);
            [app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.g_sm_L2,app.g_sm_L1,app.g_sm_Lsup]=load_flattri_smooth_geometry_cell(app.g_sm_ntri,app.g_sm_norder,app.g_sm_npoints,app.g_sm_srcvals,app.Slider_7.Value)
            refresh_geometry_cell(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.g_sm_L2,app.g_sm_L1,app.g_sm_Lsup,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value,app.ErrorDropDown.Value,app.ShowErrorCheckBox.Value,app.my_cell(:,2))
            set(app.ssurf_h_figure, 'Visible','on')
            rotate3d(app.ssurf_h_axes,'on')
            axis(app.ssurf_h_axes,'equal')
            view(az,el);
            event=0;
            ProjectionDropDown_4ValueChanged(app, event)
            AxisDropDown_4ValueChanged(app, event)
            LightDropDown_4ValueChanged(app, event)
            ShadingDropDown_4ValueChanged(app, event)          
        end

        % Value changed function: ListListBox
        function ListListBoxValueChanged(app, event)
            value = app.ListListBox.Value;
            [cx,cy]=size(app.my_cell);
            encontrado=0;
            buscado=app.ListListBox.Value
            for count=1:cx
                app.my_cell{count,1}
                app.my_cell{count,2}
%                app.ListListBox.Value
                if strcmp(app.my_cell{count,1},app.ListListBox.Value)
                  encontrado=count
                end
            end
            app.TransparencySlider.Value=app.my_cell{encontrado,2};
            app.TransparencyEditField.Value=app.my_cell{encontrado,2};
        end

        % Button pushed function: SameTraspButton
        function SameTraspButtonPushed(app, event)
            [cx,cy]=size(app.my_cell);
            for count=1:cx
              app.my_cell{count,2}=app.TransparencyEditField.Value;
            end
            
              if length(app.g_sm_F_x)>0
                 if isvalid(app.ssurf_h_axes)
                 else
                     app.ssurf_h_figure=figure('Visible','off');
                     app.ssurf_h_axes=axes;
                     view(50,20)
                 end
                [az,el] = view(app.ssurf_h_axes);
%                [app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL]=load_flattri_smooth_geometry(app.g_sm_ntri,app.g_sm_norder,app.g_sm_npoints,app.g_sm_srcvals,app.Slider_7.Value)
                refresh_geometry_cell(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.g_sm_L2,app.g_sm_L1,app.g_sm_Lsup,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value,app.ErrorDropDown.Value,app.ShowErrorCheckBox.Value,app.my_cell(:,2))
                
%refresh_geometry(app.g_sm_tri_flat_tot,app.g_sm_F_x,app.g_sm_F_y,app.g_sm_F_z,app.g_sm_COL,app.ssurf_h_axes,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)               
%               plot_go3(file,app.ssurf_h_axes,app.Slider_7.Value,app.ColorDropDown_4.Value,app.RotationaxisDropDown_2.Value)
                set(app.ssurf_h_figure, 'Visible','on')
                rotate3d(app.ssurf_h_axes,'on')
                axis(app.ssurf_h_axes,'equal')
                view(az,el);
                event=0;
                ProjectionDropDown_4ValueChanged(app, event)
                AxisDropDown_4ValueChanged(app, event)
                LightDropDown_4ValueChanged(app, event)
                ShadingDropDown_4ValueChanged(app, event)            
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SURFACESMOOTHERLabel = uilabel(app.UIFigure);
            app.SURFACESMOOTHERLabel.Text = 'SURFACE SMOOTHER';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.SetupSmootherTab = uitab(app.TabGroup2);
            app.SetupSmootherTab.Title = 'Setup Smoother';
            app.EditField = uieditfield(app.SetupSmootherTab, 'text');
            app.EditField.Value = '/usr/local/bin';
            app.EnterpathoffortrancompilertypewhichgfortranonterminalLabel = uilabel(app.SetupSmootherTab);
            app.EnterpathoffortrancompilertypewhichgfortranonterminalLabel.Text = {'Enter path of fortran compiler '; '(type "which gfortran" on terminal)'};
            app.RunSmootherTab = uitab(app.TabGroup2);
            app.RunSmootherTab.Title = 'Run Smoother';
            app.ParametersofthesurfacesmootherPanel = uipanel(app.RunSmootherTab);
            app.ParametersofthesurfacesmootherPanel.Title = 'Parameters of the surface smoother';
            app.NumberofrefinemensDropDownLabel = uilabel(app.ParametersofthesurfacesmootherPanel);
            app.NumberofrefinemensDropDownLabel.Text = 'Number of refinemens';
            app.NumberofrefinemensDropDown = uidropdown(app.ParametersofthesurfacesmootherPanel);
            app.NumberofrefinemensDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8'};
            app.NumberofrefinemensDropDown.Value = '0';
            app.AdaptiveflagDropDownLabel = uilabel(app.ParametersofthesurfacesmootherPanel);
            app.AdaptiveflagDropDownLabel.Text = 'Adaptive flag';
            app.AdaptiveflagDropDown = uidropdown(app.ParametersofthesurfacesmootherPanel);
            app.AdaptiveflagDropDown.Items = {'Non adaptive', 'Adaptive'};
            app.AdaptiveflagDropDown.Value = 'Non adaptive';
            app.Nameofthego3fileEditFieldLabel = uilabel(app.ParametersofthesurfacesmootherPanel);
            app.Nameofthego3fileEditFieldLabel.Text = 'Name of the .go3 file';
            app.Nameofthego3fileEditField = uieditfield(app.ParametersofthesurfacesmootherPanel, 'text');
            app.SmoothingparameterEditFieldLabel = uilabel(app.ParametersofthesurfacesmootherPanel);
            app.SmoothingparameterEditFieldLabel.Text = 'Smoothing parameter';
            app.SmoothingparameterEditField = uieditfield(app.ParametersofthesurfacesmootherPanel, 'numeric');
            app.SmoothingparameterEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothingparameterEditFieldValueChanged, true);
            app.SmoothingparameterEditField.Value = 10;
            app.Slider_5 = uislider(app.ParametersofthesurfacesmootherPanel);
            app.Slider_5.Limits = [0 20];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_5.Value = 10;
            app.RunSmootherButton = uibutton(app.ParametersofthesurfacesmootherPanel, 'push');
            app.RunSmootherButton.ButtonPushedFcn = createCallbackFcn(app, @RunSmootherButtonPushed, true);
            app.RunSmootherButton.Text = 'Run Smoother';
            app.OrderofthequadratureruleEditFieldLabel = uilabel(app.ParametersofthesurfacesmootherPanel);
            app.OrderofthequadratureruleEditFieldLabel.Text = {'Order of the'; 'quadrature rule'};
            app.OrderofthequadratureruleEditField = uieditfield(app.ParametersofthesurfacesmootherPanel, 'numeric');
            app.OrderofthequadratureruleEditField.Value = 12;
            app.OrderofthesmoothsurfaceEditFieldLabel = uilabel(app.ParametersofthesurfacesmootherPanel);
            app.OrderofthesmoothsurfaceEditFieldLabel.Text = {'Order of the'; 'smooth surface'};
            app.OrderofthesmoothsurfaceEditField = uieditfield(app.ParametersofthesurfacesmootherPanel, 'numeric');
            app.OrderofthesmoothsurfaceEditField.Value = 4;
            app.PrepareButton = uibutton(app.ParametersofthesurfacesmootherPanel, 'push');
            app.PrepareButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareButtonPushed, true);
            app.PrepareButton.Text = 'Prepare';
            app.SkeletonPanel = uipanel(app.RunSmootherTab);
            app.SkeletonPanel.Title = 'Skeleton';
            app.SkeletonfigurepropertiesPanel = uipanel(app.SkeletonPanel);
            app.SkeletonfigurepropertiesPanel.Title = 'Skeleton figure properties';
            app.Slider_6 = uislider(app.SkeletonfigurepropertiesPanel);
            app.Slider_6.Limits = [4 40];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.Slider_6.ValueChangingFcn = createCallbackFcn(app, @Slider_6ValueChanging, true);
            app.Slider_6.Value = 4;
            app.AxisDropDown_3Label = uilabel(app.SkeletonfigurepropertiesPanel);
            app.AxisDropDown_3Label.Text = 'Axis';
            app.AxisDropDown_3 = uidropdown(app.SkeletonfigurepropertiesPanel);
            app.AxisDropDown_3.Items = {'grid on', 'grid off', 'off'};
            app.AxisDropDown_3.ValueChangedFcn = createCallbackFcn(app, @AxisDropDown_3ValueChanged, true);
            app.AxisDropDown_3.Value = 'grid on';
            app.LightDropDown_3Label = uilabel(app.SkeletonfigurepropertiesPanel);
            app.LightDropDown_3Label.Text = 'Light';
            app.LightDropDown_3 = uidropdown(app.SkeletonfigurepropertiesPanel);
            app.LightDropDown_3.Items = {'off', 'head', 'left', 'right'};
            app.LightDropDown_3.ValueChangedFcn = createCallbackFcn(app, @LightDropDown_3ValueChanged, true);
            app.LightDropDown_3.Value = 'head';
            app.RotationaxisLabel = uilabel(app.SkeletonfigurepropertiesPanel);
            app.RotationaxisLabel.Text = {'Rotation'; 'axis'};
            app.RotationaxisDropDown = uidropdown(app.SkeletonfigurepropertiesPanel);
            app.RotationaxisDropDown.Items = {'z>z (default)', 'z>x', 'z>y'};
            app.RotationaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @RotationaxisDropDownValueChanged, true);
            app.RotationaxisDropDown.Value = 'z>z (default)';
            app.ColorDropDown_3Label = uilabel(app.SkeletonfigurepropertiesPanel);
            app.ColorDropDown_3Label.Text = 'Color';
            app.ColorDropDown_3 = uidropdown(app.SkeletonfigurepropertiesPanel);
            app.ColorDropDown_3.Items = {'tri', 'uniform', 'z', 'y', 'x'};
            app.ColorDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ColorDropDown_3ValueChanged, true);
            app.ColorDropDown_3.Value = 'z';
            app.ShadingDropDown_3Label = uilabel(app.SkeletonfigurepropertiesPanel);
            app.ShadingDropDown_3Label.Text = 'Shading';
            app.ShadingDropDown_3 = uidropdown(app.SkeletonfigurepropertiesPanel);
            app.ShadingDropDown_3.Items = {'flat', 'faceted', 'interp'};
            app.ShadingDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ShadingDropDown_3ValueChanged, true);
            app.ShadingDropDown_3.Value = 'interp';
            app.ResolutionEditField_2Label = uilabel(app.SkeletonfigurepropertiesPanel);
            app.ResolutionEditField_2Label.Text = 'Resolution';
            app.ResolutionEditField_2 = uieditfield(app.SkeletonfigurepropertiesPanel, 'numeric');
            app.ResolutionEditField_2.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditField_2ValueChanged, true);
            app.ResolutionEditField_2.Value = 4;
            app.ProjectionDropDown_3Label = uilabel(app.SkeletonfigurepropertiesPanel);
            app.ProjectionDropDown_3Label.Text = 'Projection';
            app.ProjectionDropDown_3 = uidropdown(app.SkeletonfigurepropertiesPanel);
            app.ProjectionDropDown_3.Items = {'orthographic', 'perspective'};
            app.ProjectionDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ProjectionDropDown_3ValueChanged, true);
            app.ProjectionDropDown_3.Value = 'perspective';
            app.OpenmshButton = uibutton(app.SkeletonPanel, 'push');
            app.OpenmshButton.ButtonPushedFcn = createCallbackFcn(app, @OpenmshButtonPushed, true);
            app.OpenmshButton.Text = 'Open .msh';
            app.showpathCheckBox = uicheckbox(app.SkeletonPanel);
            app.showpathCheckBox.ValueChangedFcn = createCallbackFcn(app, @showpathCheckBoxValueChanged, true);
            app.showpathCheckBox.Text = {'show'; 'path'};
            app.skeletonEditFieldLabel = uilabel(app.SkeletonPanel);
            app.skeletonEditFieldLabel.Text = 'skeleton:';
            app.skeletonEditField = uieditfield(app.SkeletonPanel, 'text');
            app.skeletonEditField.ValueChangingFcn = createCallbackFcn(app, @skeletonEditFieldValueChanging, true);
            app.SmoothSurfacePanel = uipanel(app.RunSmootherTab);
            app.SmoothSurfacePanel.Title = 'Smooth Surface';
            app.SmoothsurfacefigurepropertiesPanel = uipanel(app.SmoothSurfacePanel);
            app.SmoothsurfacefigurepropertiesPanel.Title = 'Smooth surface figure properties';
            app.ErrorDropDown = uidropdown(app.SmoothsurfacefigurepropertiesPanel);
            app.ErrorDropDown.Items = {'L2 Tail', 'L1 Tail', 'Lsup Tail'};
            app.ErrorDropDown.ValueChangedFcn = createCallbackFcn(app, @ErrorDropDownValueChanged, true);
            app.ErrorDropDown.Enable = 'off';
            app.ErrorDropDown.Value = 'L2 Tail';
            app.ShowErrorCheckBox = uicheckbox(app.SmoothsurfacefigurepropertiesPanel);
            app.ShowErrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowErrorCheckBoxValueChanged, true);
            app.ShowErrorCheckBox.Text = 'Show Error';
            app.Slider_7 = uislider(app.SmoothsurfacefigurepropertiesPanel);
            app.Slider_7.Limits = [4 40];
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @Slider_7ValueChanged, true);
            app.Slider_7.Value = 4;
            app.AxisDropDown_4Label = uilabel(app.SmoothsurfacefigurepropertiesPanel);
            app.AxisDropDown_4Label.Text = 'Axis';
            app.AxisDropDown_4 = uidropdown(app.SmoothsurfacefigurepropertiesPanel);
            app.AxisDropDown_4.Items = {'grid on', 'grid off', 'off'};
            app.AxisDropDown_4.ValueChangedFcn = createCallbackFcn(app, @AxisDropDown_4ValueChanged, true);
            app.AxisDropDown_4.Value = 'grid on';
            app.LightDropDown_4Label = uilabel(app.SmoothsurfacefigurepropertiesPanel);
            app.LightDropDown_4Label.Text = 'Light';
            app.LightDropDown_4 = uidropdown(app.SmoothsurfacefigurepropertiesPanel);
            app.LightDropDown_4.Items = {'off', 'head', 'left', 'right'};
            app.LightDropDown_4.ValueChangedFcn = createCallbackFcn(app, @LightDropDown_4ValueChanged, true);
            app.LightDropDown_4.Value = 'head';
            app.RotationaxisDropDown_2Label = uilabel(app.SmoothsurfacefigurepropertiesPanel);
            app.RotationaxisDropDown_2Label.Text = {'Rotation'; 'axis'};
            app.RotationaxisDropDown_2 = uidropdown(app.SmoothsurfacefigurepropertiesPanel);
            app.RotationaxisDropDown_2.Items = {'z>z (default)', 'z>x', 'z>y'};
            app.RotationaxisDropDown_2.ValueChangedFcn = createCallbackFcn(app, @RotationaxisDropDown_2ValueChanged, true);
            app.RotationaxisDropDown_2.Value = 'z>z (default)';
            app.ColorDropDown_4Label = uilabel(app.SmoothsurfacefigurepropertiesPanel);
            app.ColorDropDown_4Label.Text = 'Color';
            app.ColorDropDown_4 = uidropdown(app.SmoothsurfacefigurepropertiesPanel);
            app.ColorDropDown_4.Items = {'tri', 'uniform', 'z', 'y', 'x'};
            app.ColorDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ColorDropDown_4ValueChanged, true);
            app.ColorDropDown_4.Value = 'z';
            app.ShadingDropDown_4Label = uilabel(app.SmoothsurfacefigurepropertiesPanel);
            app.ShadingDropDown_4Label.Text = 'Shading';
            app.ShadingDropDown_4 = uidropdown(app.SmoothsurfacefigurepropertiesPanel);
            app.ShadingDropDown_4.Items = {'flat', 'faceted', 'interp'};
            app.ShadingDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ShadingDropDown_4ValueChanged, true);
            app.ShadingDropDown_4.Value = 'interp';
            app.ResolutionEditField_3Label = uilabel(app.SmoothsurfacefigurepropertiesPanel);
            app.ResolutionEditField_3Label.Text = 'Resolution';
            app.ResolutionEditField_3 = uieditfield(app.SmoothsurfacefigurepropertiesPanel, 'numeric');
            app.ResolutionEditField_3.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditField_3ValueChanged, true);
            app.ResolutionEditField_3.Value = 4;
            app.ProjectionDropDown_4Label = uilabel(app.SmoothsurfacefigurepropertiesPanel);
            app.ProjectionDropDown_4Label.Text = 'Projection';
            app.ProjectionDropDown_4 = uidropdown(app.SmoothsurfacefigurepropertiesPanel);
            app.ProjectionDropDown_4.Items = {'orthographic', 'perspective'};
            app.ProjectionDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ProjectionDropDown_4ValueChanged, true);
            app.ProjectionDropDown_4.Value = 'perspective';
            app.Opengo3Button = uibutton(app.SmoothSurfacePanel, 'push');
            app.Opengo3Button.ButtonPushedFcn = createCallbackFcn(app, @Opengo3ButtonPushed, true);
            app.Opengo3Button.Text = 'Open .go3';
            app.go3fileEditFieldLabel = uilabel(app.SmoothSurfacePanel);
            app.go3fileEditFieldLabel.Text = 'go3 file:';
            app.go3fileEditField = uieditfield(app.SmoothSurfacePanel, 'text');
            app.go3fileEditField.ValueChangingFcn = createCallbackFcn(app, @go3fileEditFieldValueChanging, true);
            app.showpathCheckBox_2 = uicheckbox(app.SmoothSurfacePanel);
            app.showpathCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @showpathCheckBox_2ValueChanged, true);
            app.showpathCheckBox_2.Text = {'show'; 'path'};
            app.TextArea = uitextarea(app.RunSmootherTab);
            app.SelectskeletonLabel = uilabel(app.RunSmootherTab);
            app.SelectskeletonLabel.Text = '1. Select skeleton';
            app.SelectsmoothingparametersLabel = uilabel(app.RunSmootherTab);
            app.SelectsmoothingparametersLabel.Text = '2. Select smoothing parameters';
            app.ViewresultingsmoothsurfaceLabel = uilabel(app.RunSmootherTab);
            app.ViewresultingsmoothsurfaceLabel.Text = '3. View resulting smooth surface';
            app.CheckIntersectionPanel = uipanel(app.RunSmootherTab);
            app.CheckIntersectionPanel.Title = 'Check Intersection';
            app.ListListBoxLabel = uilabel(app.CheckIntersectionPanel);
            app.ListListBoxLabel.Text = 'List';
            app.ListListBox = uilistbox(app.CheckIntersectionPanel);
            app.ListListBox.Items = {};
            app.ListListBox.ValueChangedFcn = createCallbackFcn(app, @ListListBoxValueChanged, true);
            app.ListListBox.Value = {};
            app.TransparencySlider = uislider(app.CheckIntersectionPanel);
            app.TransparencySlider.Limits = [0 1];
            app.TransparencySlider.ValueChangedFcn = createCallbackFcn(app, @TransparencySliderValueChanged, true);
            app.RemovefromlistButton = uibutton(app.CheckIntersectionPanel, 'push');
            app.RemovefromlistButton.ButtonPushedFcn = createCallbackFcn(app, @RemovefromlistButtonPushed, true);
            app.RemovefromlistButton.Text = 'Remove from list';
            app.SameTraspButton = uibutton(app.CheckIntersectionPanel, 'push');
            app.SameTraspButton.ButtonPushedFcn = createCallbackFcn(app, @SameTraspButtonPushed, true);
            app.SameTraspButton.Text = 'Same Trasp.';
            app.CheckIntersButton = uibutton(app.CheckIntersectionPanel, 'push');
            app.CheckIntersButton.ButtonPushedFcn = createCallbackFcn(app, @CheckIntersButtonPushed, true);
            app.CheckIntersButton.Text = 'Check Inters.';
            app.TransparencyEditFieldLabel = uilabel(app.CheckIntersectionPanel);
            app.TransparencyEditFieldLabel.Text = 'Transparency';
            app.TransparencyEditField = uieditfield(app.CheckIntersectionPanel, 'numeric');
            app.TransparencyEditField.ValueChangedFcn = createCallbackFcn(app, @TransparencyEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Smoother2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end